classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI_BubbleViewer               matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        NewMenu                        matlab.ui.container.Menu
        OpenfileMenu                   matlab.ui.container.Menu
        ChoosefolderMenu               matlab.ui.container.Menu
        ExportMenu                     matlab.ui.container.Menu
        matformatMenu                  matlab.ui.container.Menu
        csvformatMenu                  matlab.ui.container.Menu
        CloseMenu                      matlab.ui.container.Menu
        EditMenu                       matlab.ui.container.Menu
        UndoMenu                       matlab.ui.container.Menu
        RedoMenu                       matlab.ui.container.Menu
        CutMenu                        matlab.ui.container.Menu
        CopyMenu                       matlab.ui.container.Menu
        PasteMenu                      matlab.ui.container.Menu
        ViewMenu                       matlab.ui.container.Menu
        PreviousMenu                   matlab.ui.container.Menu
        NextMenu                       matlab.ui.container.Menu
        WindowMenu                     matlab.ui.container.Menu
        MinimiseMenu                   matlab.ui.container.Menu
        FullscreenMenu                 matlab.ui.container.Menu
        ZoomMenu                       matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        LicenseMenu                    matlab.ui.container.Menu
        DocumentationMenu              matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        STEP1Tab                       matlab.ui.container.Tab
        b_FolderSelect                 matlab.ui.control.StateButton
        DirectoryLocationLabel         matlab.ui.control.Label
        t_images                       matlab.ui.control.Table
        SampleImg                      matlab.ui.control.Image
        b_Tab1Next                     matlab.ui.control.Button
        ImagepreviewLabel              matlab.ui.control.Label
        STEP2Tab                       matlab.ui.container.Tab
        b_Tab2Next                     matlab.ui.control.Button
        b_Tab2Back                     matlab.ui.control.Button
        CalibrationPhoto               matlab.ui.control.Image
        TextCalibrationPhoto           matlab.ui.control.TextArea
        ResolutionPhoto                matlab.ui.control.Image
        TextBackgrounColour            matlab.ui.control.TextArea
        b_ManualResolution             matlab.ui.control.Button
        b_ResolutionConfirm            matlab.ui.control.Button
        b_BacgroundConfirm             matlab.ui.control.Button
        STEP3Tab                       matlab.ui.container.Tab
        TextArea_3                     matlab.ui.control.TextArea
        Image_3                        matlab.ui.control.Image
        TextArea_2                     matlab.ui.control.TextArea
        Image_2                        matlab.ui.control.Image
        BackButtonSTEP3                matlab.ui.control.Button
        NextButtonSTEP3                matlab.ui.control.Button
        Par1EditFieldLabel             matlab.ui.control.Label
        Par1EditField                  matlab.ui.control.NumericEditField
        Par2EditFieldLabel             matlab.ui.control.Label
        Par2EditField                  matlab.ui.control.NumericEditField
        Par3EditFieldLabel             matlab.ui.control.Label
        Par3EditField                  matlab.ui.control.NumericEditField
        ConfirmButton                  matlab.ui.control.Button
        ImageprocessingforsamplephotoButtonGroup  matlab.ui.container.ButtonGroup
        UsingdefaultparametersButton   matlab.ui.control.RadioButton
        ChoosingmyownparametersButton  matlab.ui.control.RadioButton
        ConfirmandprocessimageButton   matlab.ui.control.Button
        STEP4Tab                       matlab.ui.container.Tab
        NextButtonSTEP4                matlab.ui.control.Button
        BackButtonSTEP4                matlab.ui.control.Button
        StartimageanalysisButton       matlab.ui.control.Button
        HistogrambyvolumeofbubblesCheckBox  matlab.ui.control.CheckBox
        CDFCheckBox                    matlab.ui.control.CheckBox
        PDFCheckBox                    matlab.ui.control.CheckBox
        DmeanCheckBox                  matlab.ui.control.CheckBox
        D32CheckBox                    matlab.ui.control.CheckBox
        NumberofbinsEditFieldLabel     matlab.ui.control.Label
        NumberofbinsEditField          matlab.ui.control.EditField
        HistogrambynumberofbubblesCheckBox  matlab.ui.control.CheckBox
        SelectoutputsLabel             matlab.ui.control.Label
        NumberofbinsEditField_2Label   matlab.ui.control.Label
        NumberofbinsEditField_2        matlab.ui.control.EditField
        DxyCheckBox                    matlab.ui.control.CheckBox
        xEditFieldLabel                matlab.ui.control.Label
        xEditField                     matlab.ui.control.EditField
        yEditFieldLabel                matlab.ui.control.Label
        yEditField                     matlab.ui.control.EditField
        Image_4                        matlab.ui.control.Image
        TextArea_5                     matlab.ui.control.TextArea
        Image_5                        matlab.ui.control.Image
        ResultsLabel                   matlab.ui.control.Label
        TextArea_6Label                matlab.ui.control.Label
        TextArea_6                     matlab.ui.control.TextArea
        DxyCheckBox_2                  matlab.ui.control.CheckBox
        xEditField_2Label              matlab.ui.control.Label
        xEditField_2                   matlab.ui.control.EditField
        yEditField_2Label              matlab.ui.control.Label
        yEditField_2                   matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
        cm_StartAnalysis               matlab.ui.container.ContextMenu
        WhatisthisMenu                 matlab.ui.container.Menu
        cm_FolderSelect                matlab.ui.container.ContextMenu
        WhatisthisMenu_2               matlab.ui.container.Menu
        cm_TableImages                 matlab.ui.container.ContextMenu
        WhatisthisMenu_3               matlab.ui.container.Menu
    end

    % Author: Reyes, Francisco; Quintanilla, Paulina; Mesa, Diego
    % email: f.reyes@uq.edu.au,
    % Website: https://gitlab.com/frreyes1/bubble-sizer
    % Copyright Oct-2020;
    %
    %This file is part of Bubble Sizer.
    %
    %    Bubble Sizer is free software: you can redistribute it and/or modify
    %    it under the terms of the GNU General Public License as published by
    %    the Free Software Foundation version 3 only of the License.
    %
    %    Bubble Sizer is distributed in the hope that it will be useful,
    %    but WITHOUT ANY WARRANTY; without even the implied warranty of
    %    MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
    %    GNU General Public License for more details.
    %
    %    You should have received a copy of the GNU General Public License
    %    along with Bubble Sizer.  If not, see <https://www.gnu.org/licenses/>.
    %
    %------------- BEGIN CODE --------------
    properties (Access = private)
        working_folder_path % Store user selected folder with the images
        %variables to store the working images
        random_img;
        background_img;
        calibration_img;
        labelled_img;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: b_FolderSelect
        function b_FolderSelectValueChanged(app, event)
            user_path = uigetdir;        % Calls 'uigetdir' to obtain the directory location from the user
            figure(app.GUI_BubbleViewer) %Bring back the app to the front
            if ~user_path
                return
            end
            app.DirectoryLocationLabel.Text = user_path;  % Sets the label text to be the selected path
            
            %Load file names in folder
            folder_files = dir(user_path);
            folder_files = folder_files(~[folder_files.isdir]); %exclude folders
            
            %% Separate image files from whatever else it might be there in the folder
            %figure out the extension if the image files (.jpeg, tiff, png, etc...)
            [~, ~, fextensions] = cellfun(@fileparts, {folder_files.name}, 'UniformOutput', false);
            [s, ~, j] = unique(fextensions);
            img_extension = s{mode(j)};
            %with the extension we can now get the image file list
            img_files = dir([user_path '\*' img_extension]);
            image_names = {img_files(:).name}';         % Gets the name of the files/folders of the contents and stores them appropriately in a cell array
            app.t_images.Data = image_names;            % Displays the directory information to the UITable.
            app.working_folder_path = [user_path '/'];  %store the user's pick. Add final stroke
            app.b_Tab1Next.Enable = true;               %Enable Tab1's 'Next' button
        end

        % Cell selection callback: t_images
        function t_imagesCellSelection(app, event)
            selection_idx = event.Indices(1); %get row selection index
            image_names = app.t_images.DisplayData;
            selected_img = fullfile( app.working_folder_path, image_names(selection_idx) );
            app.random_img = imread(selected_img{1});
            app.SampleImg.ImageSource = selected_img{1};
        end

        % Menu selected function: LicenseMenu
        function LicenseMenuSelected(app, event)
            helpdlg({'Bubble Sizer  Copyright (C) 2020'; ' '; ...
                'Bubble Sizer is free software: you can redistribute it and/or modify'; ...
                'it under the terms of the GNU General Public License as published'; ...
                'by the Free Software Foundation version 3 only of the License'}, ...
                'License');
        end

        % Button pushed function: b_Tab1Next
        function b_Tab1NextPushed(app, event)
            app.TabGroup.SelectedTab = app.STEP2Tab;
        end

        % Menu selected function: WhatisthisMenu_2
        function WhatisthisMenu_2Selected(app, event)
            helpdlg({'Browse folder where images are located'; ' '; ...
                'This will be your working directory'}, ...
                'Find images');
        end

        % Menu selected function: WhatisthisMenu_3
        function WhatisthisMenu_3Selected(app, event)
            helpdlg({'Click on file names to preview';' '}, 'Image file list');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI_BubbleViewer = uifigure('Visible', 'off');
            app.GUI_BubbleViewer.Name = 'Bubble Sizer';
            app.FileMenu = uimenu(app.GUI_BubbleViewer);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Text = 'New';
            app.OpenfileMenu = uimenu(app.FileMenu);
            app.OpenfileMenu.Text = 'Open file';
            app.ChoosefolderMenu = uimenu(app.OpenfileMenu);
            app.ChoosefolderMenu.Text = 'Choose folder';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Text = 'Export';
            app.matformatMenu = uimenu(app.ExportMenu);
            app.matformatMenu.Text = '.mat format';
            app.csvformatMenu = uimenu(app.ExportMenu);
            app.csvformatMenu.Text = '.csv format';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.Text = 'Close';
            app.EditMenu = uimenu(app.GUI_BubbleViewer);
            app.EditMenu.Text = 'Edit';
            app.UndoMenu = uimenu(app.EditMenu);
            app.UndoMenu.Text = 'Undo';
            app.RedoMenu = uimenu(app.EditMenu);
            app.RedoMenu.Text = 'Redo';
            app.CutMenu = uimenu(app.EditMenu);
            app.CutMenu.Text = 'Cut';
            app.CopyMenu = uimenu(app.EditMenu);
            app.CopyMenu.Text = 'Copy';
            app.PasteMenu = uimenu(app.EditMenu);
            app.PasteMenu.Text = 'Paste';
            app.ViewMenu = uimenu(app.GUI_BubbleViewer);
            app.ViewMenu.Text = 'View';
            app.PreviousMenu = uimenu(app.ViewMenu);
            app.PreviousMenu.Text = 'Previous';
            app.NextMenu = uimenu(app.ViewMenu);
            app.NextMenu.Text = 'Next';
            app.WindowMenu = uimenu(app.GUI_BubbleViewer);
            app.WindowMenu.Text = 'Window';
            app.MinimiseMenu = uimenu(app.WindowMenu);
            app.MinimiseMenu.Text = 'Minimise';
            app.FullscreenMenu = uimenu(app.WindowMenu);
            app.FullscreenMenu.Text = 'Full screen';
            app.ZoomMenu = uimenu(app.WindowMenu);
            app.ZoomMenu.Text = 'Zoom';
            app.HelpMenu = uimenu(app.GUI_BubbleViewer);
            app.HelpMenu.Text = 'Help';
            app.LicenseMenu = uimenu(app.HelpMenu);
            app.LicenseMenu.MenuSelectedFcn = createCallbackFcn(app, @LicenseMenuSelected, true);
            app.LicenseMenu.Text = 'License';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.Text = 'Documentation';
            app.TabGroup = uitabgroup(app.GUI_BubbleViewer);
            app.STEP1Tab = uitab(app.TabGroup);
            app.STEP1Tab.Title = 'STEP 1';
            app.b_FolderSelect = uibutton(app.STEP1Tab, 'state');
            app.b_FolderSelect.ValueChangedFcn = createCallbackFcn(app, @b_FolderSelectValueChanged, true);
            app.b_FolderSelect.Text = 'Select folder';
            app.DirectoryLocationLabel = uilabel(app.STEP1Tab);
            app.DirectoryLocationLabel.Text = 'Directory location';
            app.t_images = uitable(app.STEP1Tab);
            app.t_images.ColumnName = {'Select photos from directory contents'};
            app.t_images.RowName = {};
            app.t_images.CellSelectionCallback = createCallbackFcn(app, @t_imagesCellSelection, true);
            app.SampleImg = uiimage(app.STEP1Tab);
            app.b_Tab1Next = uibutton(app.STEP1Tab, 'push');
            app.b_Tab1Next.ButtonPushedFcn = createCallbackFcn(app, @b_Tab1NextPushed, true);
            app.b_Tab1Next.Enable = 'off';
            app.b_Tab1Next.Text = 'Next >>';
            app.ImagepreviewLabel = uilabel(app.STEP1Tab);
            app.ImagepreviewLabel.Text = 'Image preview';
            app.STEP2Tab = uitab(app.TabGroup);
            app.STEP2Tab.Title = 'STEP 2';
            app.b_Tab2Next = uibutton(app.STEP2Tab, 'push');
            app.b_Tab2Next.Enable = 'off';
            app.b_Tab2Next.Text = 'Next >>';
            app.b_Tab2Back = uibutton(app.STEP2Tab, 'push');
            app.b_Tab2Back.Text = '<< Back';
            app.CalibrationPhoto = uiimage(app.STEP2Tab);
            app.TextCalibrationPhoto = uitextarea(app.STEP2Tab);
            app.TextCalibrationPhoto.Value = {'Calibration photo'};
            app.ResolutionPhoto = uiimage(app.STEP2Tab);
            app.TextBackgrounColour = uitextarea(app.STEP2Tab);
            app.TextBackgrounColour.Value = {'Background colour'};
            app.b_ManualResolution = uibutton(app.STEP2Tab, 'push');
            app.b_ManualResolution.Text = 'Calibrate resolution manually';
            app.b_ResolutionConfirm = uibutton(app.STEP2Tab, 'push');
            app.b_ResolutionConfirm.Text = 'Confirm';
            app.b_BacgroundConfirm = uibutton(app.STEP2Tab, 'push');
            app.b_BacgroundConfirm.Text = 'Confirm';
            app.STEP3Tab = uitab(app.TabGroup);
            app.STEP3Tab.Title = 'STEP 3';
            app.TextArea_3 = uitextarea(app.STEP3Tab);
            app.TextArea_3.Value = {'Processed image preview'};
            app.Image_3 = uiimage(app.STEP3Tab);
            app.TextArea_2 = uitextarea(app.STEP3Tab);
            app.TextArea_2.Value = {'Sample photo preview'};
            app.Image_2 = uiimage(app.STEP3Tab);
            app.BackButtonSTEP3 = uibutton(app.STEP3Tab, 'push');
            app.BackButtonSTEP3.Text = '<< Back';
            app.NextButtonSTEP3 = uibutton(app.STEP3Tab, 'push');
            app.NextButtonSTEP3.Enable = 'off';
            app.NextButtonSTEP3.Text = 'Next >>';
            app.Par1EditFieldLabel = uilabel(app.STEP3Tab);
            app.Par1EditFieldLabel.Text = 'Par1';
            app.Par1EditField = uieditfield(app.STEP3Tab, 'numeric');
            app.Par2EditFieldLabel = uilabel(app.STEP3Tab);
            app.Par2EditFieldLabel.Text = 'Par2';
            app.Par2EditField = uieditfield(app.STEP3Tab, 'numeric');
            app.Par3EditFieldLabel = uilabel(app.STEP3Tab);
            app.Par3EditFieldLabel.Text = 'Par3';
            app.Par3EditField = uieditfield(app.STEP3Tab, 'numeric');
            app.ConfirmButton = uibutton(app.STEP3Tab, 'push');
            app.ConfirmButton.Text = 'Confirm';
            app.ImageprocessingforsamplephotoButtonGroup = uibuttongroup(app.STEP3Tab);
            app.ImageprocessingforsamplephotoButtonGroup.Title = 'Image processing for sample photo';
            app.UsingdefaultparametersButton = uiradiobutton(app.ImageprocessingforsamplephotoButtonGroup);
            app.UsingdefaultparametersButton.Text = 'Using default parameters';
            app.UsingdefaultparametersButton.Value = true;
            app.ChoosingmyownparametersButton = uiradiobutton(app.ImageprocessingforsamplephotoButtonGroup);
            app.ChoosingmyownparametersButton.Text = 'Choosing my own parameters';
            app.ConfirmandprocessimageButton = uibutton(app.STEP3Tab, 'push');
            app.ConfirmandprocessimageButton.Text = 'Confirm and process image';
            app.STEP4Tab = uitab(app.TabGroup);
            app.STEP4Tab.Title = 'STEP 4';
            app.NextButtonSTEP4 = uibutton(app.STEP4Tab, 'push');
            app.NextButtonSTEP4.Enable = 'off';
            app.NextButtonSTEP4.Text = 'Next >>';
            app.BackButtonSTEP4 = uibutton(app.STEP4Tab, 'push');
            app.BackButtonSTEP4.Text = '<< Back';
            app.StartimageanalysisButton = uibutton(app.STEP4Tab, 'push');
            app.StartimageanalysisButton.Text = 'Start image analysis';
            app.HistogrambyvolumeofbubblesCheckBox = uicheckbox(app.STEP4Tab);
            app.HistogrambyvolumeofbubblesCheckBox.Text = 'Histogram by volume of bubbles';
            app.HistogrambyvolumeofbubblesCheckBox.Value = true;
            app.CDFCheckBox = uicheckbox(app.STEP4Tab);
            app.CDFCheckBox.Text = 'CDF';
            app.CDFCheckBox.Value = true;
            app.PDFCheckBox = uicheckbox(app.STEP4Tab);
            app.PDFCheckBox.Text = 'PDF';
            app.PDFCheckBox.Value = true;
            app.DmeanCheckBox = uicheckbox(app.STEP4Tab);
            app.DmeanCheckBox.Text = 'D mean';
            app.DmeanCheckBox.Value = true;
            app.D32CheckBox = uicheckbox(app.STEP4Tab);
            app.D32CheckBox.Text = 'D32';
            app.D32CheckBox.Value = true;
            app.NumberofbinsEditFieldLabel = uilabel(app.STEP4Tab);
            app.NumberofbinsEditFieldLabel.Text = 'Number of bins';
            app.NumberofbinsEditField = uieditfield(app.STEP4Tab, 'text');
            app.HistogrambynumberofbubblesCheckBox = uicheckbox(app.STEP4Tab);
            app.HistogrambynumberofbubblesCheckBox.Text = 'Histogram by number of bubbles';
            app.HistogrambynumberofbubblesCheckBox.Value = true;
            app.SelectoutputsLabel = uilabel(app.STEP4Tab);
            app.SelectoutputsLabel.Text = 'Select output(s):';
            app.NumberofbinsEditField_2Label = uilabel(app.STEP4Tab);
            app.NumberofbinsEditField_2Label.Text = 'Number of bins';
            app.NumberofbinsEditField_2 = uieditfield(app.STEP4Tab, 'text');
            app.DxyCheckBox = uicheckbox(app.STEP4Tab);
            app.DxyCheckBox.Text = 'D xy';
            app.DxyCheckBox.Value = true;
            app.xEditFieldLabel = uilabel(app.STEP4Tab);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.STEP4Tab, 'text');
            app.yEditFieldLabel = uilabel(app.STEP4Tab);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.STEP4Tab, 'text');
            app.Image_4 = uiimage(app.STEP4Tab);
            app.TextArea_5 = uitextarea(app.STEP4Tab);
            app.TextArea_5.Value = {'Random photo'};
            app.Image_5 = uiimage(app.STEP4Tab);
            app.ResultsLabel = uilabel(app.STEP4Tab);
            app.ResultsLabel.Text = 'Results:';
            app.TextArea_6Label = uilabel(app.STEP4Tab);
            app.TextArea_6Label.Text = 'Text Area';
            app.TextArea_6 = uitextarea(app.STEP4Tab);
            app.DxyCheckBox_2 = uicheckbox(app.STEP4Tab);
            app.DxyCheckBox_2.Text = 'D xy';
            app.DxyCheckBox_2.Value = true;
            app.xEditField_2Label = uilabel(app.STEP4Tab);
            app.xEditField_2Label.Text = 'x';
            app.xEditField_2 = uieditfield(app.STEP4Tab, 'text');
            app.yEditField_2Label = uilabel(app.STEP4Tab);
            app.yEditField_2Label.Text = 'y';
            app.yEditField_2 = uieditfield(app.STEP4Tab, 'text');
            app.UIAxes = uiaxes(app.STEP4Tab);
            title(app.UIAxes, 'Histogram preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.cm_StartAnalysis = uicontextmenu(app.GUI_BubbleViewer);
            % Assign app.cm_StartAnalysis
            app.StartimageanalysisButton.ContextMenu = app.cm_StartAnalysis;
            app.WhatisthisMenu = uimenu(app.cm_StartAnalysis);
            app.WhatisthisMenu.Text = 'What is this?';
            app.cm_FolderSelect = uicontextmenu(app.GUI_BubbleViewer);
            % Assign app.cm_FolderSelect
            app.b_FolderSelect.ContextMenu = app.cm_FolderSelect;
            app.WhatisthisMenu_2 = uimenu(app.cm_FolderSelect);
            app.WhatisthisMenu_2.MenuSelectedFcn = createCallbackFcn(app, @WhatisthisMenu_2Selected, true);
            app.WhatisthisMenu_2.Text = 'What is this?';
            app.cm_TableImages = uicontextmenu(app.GUI_BubbleViewer);
            % Assign app.cm_TableImages
            app.t_images.ContextMenu = app.cm_TableImages;
            app.WhatisthisMenu_3 = uimenu(app.cm_TableImages);
            app.WhatisthisMenu_3.MenuSelectedFcn = createCallbackFcn(app, @WhatisthisMenu_3Selected, true);
            app.WhatisthisMenu_3.Text = 'What is this?';
            % Show the figure after all components are created
            app.GUI_BubbleViewer.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUI_BubbleViewer)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI_BubbleViewer)
        end
    end
end